classdef PROEYCTO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        DisenadorDeFiltros            matlab.ui.container.TabGroup
        DisearfiltroTab               matlab.ui.container.Tab
        RadioDropDown                 matlab.ui.control.DropDown
        RadioDropDownLabel            matlab.ui.control.Label
        FrecuenciacentralEditField    matlab.ui.control.NumericEditField
        FrecuenciacentralEditFieldLabel  matlab.ui.control.Label
        OrdenEditField                matlab.ui.control.NumericEditField
        OrdenEditFieldLabel           matlab.ui.control.Label
        Frecuenciadecorte2EditField   matlab.ui.control.NumericEditField
        Frecuenciadecorte2EditFieldLabel  matlab.ui.control.Label
        Frecuenciadecorte1EditField   matlab.ui.control.NumericEditField
        Frecuenciadecorte1EditFieldLabel  matlab.ui.control.Label
        TipodefiltroDropDown          matlab.ui.control.DropDown
        TipodefiltroDropDownLabel     matlab.ui.control.Label
        FiltrarButton                 matlab.ui.control.Button
        GuardarButton                 matlab.ui.control.Button
        CargarflitroDropDown          matlab.ui.control.DropDown
        CargarflitroDropDownLabel     matlab.ui.control.Label
        FormaDropDown                 matlab.ui.control.DropDown
        FormaDropDownLabel            matlab.ui.control.Label
        VentanaDropDown               matlab.ui.control.DropDown
        VentanaDropDownLabel          matlab.ui.control.Label
        Tree                          matlab.ui.container.CheckBoxTree
        EfectosNode                   matlab.ui.container.TreeNode
        SlowedNode                    matlab.ui.container.TreeNode
        ReverbNode                    matlab.ui.container.TreeNode
        FastNode                      matlab.ui.container.TreeNode
        DuraciondelfiltroButtonGroup  matlab.ui.container.ButtonGroup
        FIRButton                     matlab.ui.control.RadioButton
        IIRButton                     matlab.ui.control.RadioButton
        VerfiltroTab                  matlab.ui.container.Tab
        FiltroFaseButtonGroup         matlab.ui.container.ButtonGroup
        radButton_filtro              matlab.ui.control.RadioButton
        gradosButton_filtro           matlab.ui.control.RadioButton
        FiltroFrecButtonGroup         matlab.ui.container.ButtonGroup
        radmButton_filtro             matlab.ui.control.RadioButton
        HzButton_filtro               matlab.ui.control.RadioButton
        FiltroAmpButtonGroup          matlab.ui.container.ButtonGroup
        dBbutton_filtro               matlab.ui.control.RadioButton
        absButton_filtro              matlab.ui.control.RadioButton
        FiltroMagnitudAxes            matlab.ui.control.UIAxes
        FiltroFaseAxes                matlab.ui.control.UIAxes
        ProcesadaPanel                matlab.ui.container.Panel
        ProcFrecButtonGroup           matlab.ui.container.ButtonGroup
        radmButton_proc               matlab.ui.control.RadioButton
        HzButton_proc                 matlab.ui.control.RadioButton
        ProcAmpButtonGroup            matlab.ui.container.ButtonGroup
        dBbutton_proc                 matlab.ui.control.RadioButton
        absButton_proc                matlab.ui.control.RadioButton
        ProcesadaTiempoAxes           matlab.ui.control.UIAxes
        ProcesadaEspectroAxes         matlab.ui.control.UIAxes
        SinProcesarPanel              matlab.ui.container.Panel
        FsEditField                   matlab.ui.control.NumericEditField
        FsEditFieldLabel              matlab.ui.control.Label
        OrgFrecButtonGroup            matlab.ui.container.ButtonGroup
        radmButton_org                matlab.ui.control.RadioButton
        HzButton_org                  matlab.ui.control.RadioButton
        OrgAmpButtonGroup             matlab.ui.container.ButtonGroup
        dBbutton_org                  matlab.ui.control.RadioButton
        absButton_org                 matlab.ui.control.RadioButton
        OriginalEspectroAxes          matlab.ui.control.UIAxes
        OriginalTiempoAxes            matlab.ui.control.UIAxes
        TipoDeProcesamiento           matlab.ui.container.TabGroup
        LocalTab                      matlab.ui.container.Tab
        SonidoButtonGroup             matlab.ui.container.ButtonGroup
        DetenerButton                 matlab.ui.control.Button
        ReanudarButton                matlab.ui.control.Button
        PausarButton                  matlab.ui.control.Button
        ReproducirButton              matlab.ui.control.Button
        ListBox_LocalFile             matlab.ui.control.ListBox
        SealListBoxLabel              matlab.ui.control.Label
        TiempoRealTab                 matlab.ui.container.Tab
        PuertoDropDown                matlab.ui.control.DropDown
        PuertoDropDownLabel           matlab.ui.control.Label
        FrecMuestreoEditField         matlab.ui.control.NumericEditField
        FrecMuestreoEditFieldLabel    matlab.ui.control.Label
        DuracionsEditField            matlab.ui.control.NumericEditField
        DuracionsEditFieldLabel       matlab.ui.control.Label
        CapturarButton                matlab.ui.control.Button
    end


    properties (Access = private)
        % Description
        Fs
        Ts
        x
        y
        player

        orden_fir   % Orden del filtro FIR
        fc_fir % Orden del filtro FIR
        wc_norm_fir % Frecuencia de corte digital normalizada del FIR
        wc2_norm_fir
        ventana_fir % Ventana del FIR
        bn_fir % Coeficientes numerador del filtro FIR
        an_fir % Coeficientes denominador del filtro FIR

        type_fir
        fc2_fir % Description
        two_freq_flag
    end


    methods (Access = private)

        % function results = func(app)

        function [f_vector, X_mag] = onesided_spectrum(app)
            N = length(app.x);               % Número de muestras
            X = fft(app.x);                  % Transformada rápida de Fourier
            X_mag = abs(X/N);                % Magnitud normalizada
            X_mag = X_mag(1:N/2+1);          % Parte unilateral
            X_mag(2:end-1) = 2*X_mag(2:end-1);    % Compensacion
            f_vector = (0:N/2) * (app.Fs / N);    % Vector de frecuencias
        end

        function Generar_Filtro_FIR(app)

            %tomar los coeficientes globales para obtyener el espectro en
            %frecuencia del filtro
            app.bn_fir = fir1(app.orden_fir, app.wc_norm_fir, app.type_fir, app.ventana_fir);
            app.an_fir = zeros(size(app.bn_fir)); app.an_fir(1) = 1;

            [filter_H, filter_f] = freqz(app.bn_fir, app.an_fir, length(app.x),app.Fs);  % H: respuesta compleja, f: vector de frecuencias (Hz)

            if app.FiltroAmpButtonGroup.SelectedObject == app.dBbutton_filtro
                Y = 20 * log10(abs(filter_H) + eps); % evitar log(0)
                ylabel(app.FiltroMagnitudAxes, 'Amplitud (dB)');
                ylim(app.FiltroMagnitudAxes, [-100 0]);
            end
            if app.FiltroAmpButtonGroup.SelectedObject == app.absButton_filtro
                Y = abs(filter_H);
                ylabel(app.FiltroMagnitudAxes, 'Amplitud (abs)')
                ylim(app.FiltroMagnitudAxes, [0 1.2]);
            end
            if app.FiltroFrecButtonGroup.SelectedObject == app.radmButton_filtro
                X = linspace(0, pi, length(app.x));  % rad/muestra
                fnyq = pi;
                xlabel(app.FiltroMagnitudAxes, 'Frecuencia (rad/muestra)');
                xlabel(app.FiltroFaseAxes, 'Frecuencia (rad/muestra)');
            end
            if app.FiltroFrecButtonGroup.SelectedObject == app.HzButton_filtro
                X = filter_f / 1000;
                fnyq = app.Fs / 2 / 1000;  % Nyquist en kHz
                xlabel(app.FiltroMagnitudAxes, 'Frecuencia (kHz)');
                xlabel(app.FiltroFaseAxes, 'Frecuencia (kHz)');
            end
            if app.FiltroFaseButtonGroup.SelectedObject == app.radButton_filtro
                F = unwrap(rad2deg(angle(filter_H)));
                ylabel(app.FiltroFaseAxes, 'Fase (rad)');

            end
            if app.FiltroFaseButtonGroup.SelectedObject == app.gradosButton_filtro
                F = unwrap(angle(filter_H));
                ylabel(app.FiltroFaseAxes, 'Fase (grados)');
            end


            plot(app.FiltroMagnitudAxes, X, Y);
            plot(app.FiltroFaseAxes, X, F);
            xlim(app.FiltroMagnitudAxes, [0 fnyq]);
            xlim(app.FiltroFaseAxes, [0 fnyq]);

            app.DisenadorDeFiltros.SelectedTab = app.VerfiltroTab;

            % app.y = filter(app.bn_fir, app.an_fir, app.x);
            % n = length(app.x);
        end

        function Generar_Filtro_IIR(app)
            % Obtener orden y frecuencias

            Ts1 = 1/fs1;
            Fc1_1 = 300;
            orden1_1 = 8;
            wc1_1_norm = 2*Fc1_1*Ts1;  % (2*pi*Fc)*Ts/pi
            [b1_1, a1_1] = butter(orden1_1, wc1_1_norm, 'low');
            orden = app.orden_fir;
            fc1 = app.fc_fir;
            fc2 = app.fc2_fir;
            Fs = app.Fs;
            Ts = 1/Fs;

            % Calcular frecuencia normalizada
            if app.type_fir == "bandpass" || app.type_fir == "stop"
                wc = [2*fc1*Ts, 2*fc2*Ts];
            else
                wc = 2*fc1*Ts;
            end

            % Por ahora: usamos siempre Butterworth como default
            [app.bn_fir, app.an_fir] = butter(orden, wc, app.type_fir);

            % Filtrado
            app.y = filter(app.bn_fir, app.an_fir, app.x);
            n = length(app.x);

            % Respuesta en frecuencia
            [filter_H, filter_f] = freqz(app.bn_fir, app.an_fir, n, app.Fs);
            plot(app.IIR_Mag, filter_f, abs(filter_H));
            plot(app.IIR_Fase, filter_f, angle(filter_H));
        end


        function RegenerarVentana(app)
            value = app.VentanaDropDown.Value;
            switch (value)
                case 'Rectangular'
                    app.ventana_fir = rectwin(app.orden_fir + 1);

                case 'Bartlett'
                    app.ventana_fir = bartlett(app.orden_fir + 1);

                case 'Hamming'
                    app.ventana_fir = hamming(app.orden_fir + 1);

                case 'Blackman'
                    app.ventana_fir = blackman(app.orden_fir + 1);

                case 'Kaiser'
                    app.ventana_fir = kaiser(app.orden_fir + 1);

                otherwise

            end
        end

        function RegenerarForma(app)
            value = app.FormaDropDown.Value;
            switch (value)
                case 'Butterworth'


                case 'Chebyshev I'


                case 'Chebyshev II'


                case 'Eliptico'


            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TipoDeProcesamiento.SelectedTab = app.LocalTab;
            app.ListBox_LocalFile.Value = 'Ninguno';
            app.DetenerButton.Enable = "off";
            app.ReproducirButton.Enable = "off";
            app.PausarButton.Enable = "off";
            app.ReanudarButton.Enable = 'off';
            app.DisenadorDeFiltros.SelectedTab = app.DisearfiltroTab;
            app.DuraciondelfiltroButtonGroup.SelectedObject = app.FIRButton;
            app.VentanaDropDown.Value = 'Hamming';
            app.FormaDropDown.Value = 'Butterworth';
            app.FormaDropDown.Enable = 'off';
            app.type_fir = 'LOW';
            app.Frecuenciadecorte2EditField.Enable = 'off';
            app.RadioDropDown.Enable = 'off';
            app.FrecuenciacentralEditField.Enable = 'off';
            app.OrdenEditField.Value = 0;
            app.FiltrarButton.Enable = 'on';
            app.GuardarButton.Enable = 'off';
            app.FiltroFaseButtonGroup.SelectedObject = app.gradosButton_filtro;
            app.FiltroFrecButtonGroup.SelectedObject = app.HzButton_filtro;
            app.FiltroAmpButtonGroup.SelectedObject = app.absButton_filtro;
            app.OrgFrecButtonGroup.SelectedObject = app.HzButton_org;
            app.OrgAmpButtonGroup.SelectedObject = app.absButton_org;
            app.ProcFrecButtonGroup.SelectedObject = app.HzButton_proc;
            app.ProcAmpButtonGroup.SelectedObject = app.absButton_proc;

        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Selection changed function: DuraciondelfiltroButtonGroup
        function DuraciondelfiltroButtonGroupSelectionChanged(app, event)
            selectedButton = app.DuraciondelfiltroButtonGroup.SelectedObject;

            if (selectedButton == app.IIRButton)
                app.VentanaDropDown.Enable = "off";
                app.VentanaDropDown.Visible = "off";
                app.FormaDropDown.Enable = "on";
                app.FormaDropDown.Visible = "on";
            elseif (selectedButton == app.FIRButton)
                app.VentanaDropDown.Enable = "on";
                app.VentanaDropDown.Visible = "on";
                app.FormaDropDown.Enable = "off";
                app.FormaDropDown.Visible = "off";
            end
        end

        % Button pushed function: CapturarButton
        function CapturarButtonPushed(app, event)
            if app.FrecMuestreoEditField.Value == 0

                uialert(app.UIFigure, ...
                    'Debe ingresar un valor válido para la frecuencia de muestreo antes de capturar la señal.', ...
                    'Frecuencia de muestreo no definida');
                % Salir del callback sin hacer cambios
                return;
            end
            if app.DuracionsEditField.Value == 0

                uialert(app.UIFigure, ...
                    'Debe ingresar un valor válido para la duración antes de capturar la señal.', ...
                    'Duración no definida');
                % Salir del callback sin hacer cambios
                return;
            end
            app.Fs = app.FrecMuestreoEditField.Value;
            app.Ts = 1/app.FrecMuestreoEditField.Value;;  % Periodo de muestreo
            duration = app.DuracionsEditField.Value; % 5 segundos de duración
            N = duration/app.Ts; % Cantidad de muestras en 5 segundos
            baudRate = 115200; % Tasa de baudios configurada en Cube

            datos = zeros(1, N); % Vector de datos inicial

            s = serialport("COM9", baudRate); % Creacion del objeto puerto serie

            flush(s); % Limpieza del puerto
            %flushinput(s)

            for i = 1:N % Leer n veces
                linea = readline(s); % Obtener datos hasta salto de línea
                % linea = fgetl(s);
                datos(i) = str2double(linea); % Guardar dato en vector
            end

            clear s; % Cerrar puerto
            n = 0: N-1; % Indices de señal discreta
            t = n*app.Ts; % Vector tiempo a periodo de muestreo
            voltajes = datos*(3.3/4095); % Conversión a voltaje
            app.x = voltajes;
            %app.OriginalEspectroAxes.XLim = [0 1000];
            [V_f, V_mag] = onesided_spectrum(app);
            plot(app.OriginalEspectroAxes, V_f, V_mag);
            plot(app.OriginalTiempoAxes , t ,app.x);

        end

        % Value changed function: ListBox_LocalFile
        function ListBox_LocalFileValueChanged(app, event)
            value = app.ListBox_LocalFile.Value;
            switch value

                case 'Guitarra'
                    app.DetenerButton.Enable = "on";
                    app.ReproducirButton.Enable = "on";
                    app.PausarButton.Enable = "on";
                    app.ReanudarButton.Enable = 'on';

                    app.OriginalEspectroAxes.XLim = [0 1000];

                    [app.x, app.Fs] = audioread("Guitarra.mp3");
                    app.Ts = 1/app.Fs;
                    app.x =  app.x(:, 2);
                    t = ( 0 : length (app.x) - 1)/app.Fs;
                    plot(app.OriginalTiempoAxes, t, app.x);

                    [xf, X_mag] = onesided_spectrum(app);
                    plot(app.OriginalEspectroAxes, xf, X_mag);


                case 'Bajo'

                    app.DetenerButton.Enable = "on";
                    app.ReproducirButton.Enable = "on";
                    app.PausarButton.Enable = "on";
                    app.ReanudarButton.Enable = 'on';


                    app.OriginalEspectroAxes.XLim = [0 1000];


                    [app.x, app.Fs] = audioread("bass.wav");
                    app.Ts = 1/app.Fs;
                    t = ( 0 : length (app.x) - 1)/app.Fs;
                    plot(app.OriginalTiempoAxes, t, app.x);

                    [xf, X_mag] = onesided_spectrum(app);
                    plot(app.OriginalEspectroAxes, xf, X_mag);


                case 'ECG'

                    app.DetenerButton.Enable = "off";
                    app.ReproducirButton.Enable = "off";
                    app.PausarButton.Enable = "off";
                    app.ReanudarButton.Enable = 'off';

                    app.OriginalEspectroAxes.XLim = [0 300];


                    %load("ecg_data.mat", "ecg_noisy");
                    load("ej_ecg.mat", "ecg_noisy");
                    app.Fs = 300;
                    app.Ts = 1/app.Fs;
                    app.x = ecg_noisy;
                    t = ( 0 : length (app.x) - 1)/app.Fs;
                    plot(app.OriginalTiempoAxes, t, app.x);

                    [xf, X_mag] = onesided_spectrum(app);
                    plot(app.OriginalEspectroAxes, xf, X_mag);


                case 'AAAA'

                    app.DetenerButton.Enable = "on";
                    app.ReproducirButton.Enable = "on";
                    app.PausarButton.Enable = "on";
                    app.ReanudarButton.Enable = 'on';

                    app.OriginalEspectroAxes.XLim = [0 3000];


                    [app.x, app.Fs] = audioread("AAAA.mp3");
                    app.Ts = 1/app.Fs;
                    t = ( 0 : length (app.x) - 1)/app.Fs;
                    plot(app.OriginalTiempoAxes, t, app.x);

                    [xf, X_mag] = onesided_spectrum(app);
                    plot(app.OriginalEspectroAxes, xf, X_mag);


                case 'Dona e mobile'

                    app.DetenerButton.Enable = "on";
                    app.ReproducirButton.Enable = "on";
                    app.PausarButton.Enable = "on";
                    app.ReanudarButton.Enable = 'on';

                    app.OriginalEspectroAxes.XLim = [0 3000];


                    [app.x, app.Fs] = audioread("Dona.mp3");
                    app.Ts = 1/app.Fs;
                    t = ( 0 : length (app.x) - 1)/app.Fs;
                    plot(app.OriginalTiempoAxes, t, app.x);

                    [xf, X_mag] = onesided_spectrum(app);
                    plot(app.OriginalEspectroAxes, xf, X_mag);


                case 'Imperial march'
                    app.DetenerButton.Enable = "on";
                    app.ReproducirButton.Enable = "on";
                    app.PausarButton.Enable = "on";
                    app.ReanudarButton.Enable = 'on';

                    app.OriginalEspectroAxes.XLim = [0 3000];


                    [app.x, app.Fs] = audioread("Imperial.mp3");
                    app.Ts = 1/app.Fs;
                    t = ( 0 : length (app.x) - 1)/app.Fs;
                    plot(app.OriginalTiempoAxes, t, app.x);

                    [xf, X_mag] = onesided_spectrum(app);
                    plot(app.OriginalEspectroAxes, xf, X_mag);


                case 'Blah'

                    app.DetenerButton.Enable = "on";
                    app.ReproducirButton.Enable = "on";
                    app.PausarButton.Enable = "on";
                    app.ReanudarButton.Enable = 'on';

                    app.OriginalEspectroAxes.XLim = [0 1000];


                    [app.x, app.Fs] = audioread("Blah.mp3");
                    app.Ts = 1/app.Fs;
                    app.x =  app.x(:, 2);
                    t = ( 0 : length (app.x) - 1)/app.Fs;
                    plot(app.OriginalTiempoAxes, t, app.x);

                    [xf, X_mag] = onesided_spectrum(app);
                    plot(app.OriginalEspectroAxes, xf, X_mag);

                otherwise
                    app.DetenerButton.Enable = "off";
                    app.ReproducirButton.Enable = "off";
                    app.PausarButton.Enable = "off";
                    app.ReanudarButton.Enable = 'off';


            end
            app.FsEditField.Value = app.Fs;
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            app.player = audioplayer(app.x, app.Fs);
            play(app.player);
        end

        % Button pushed function: PausarButton
        function PausarButtonPushed(app, event)
            if isplaying(app.player)
                pause(app.player);
            end
        end

        % Button pushed function: ReanudarButton
        function ReanudarButtonPushed(app, event)
            resume(app.player);
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            if isplaying(app.player)
                stop(app.player);
            end
        end

        % Value changed function: VentanaDropDown
        function VentanaDropDownValueChanged(app, event)
            RegenerarVentana(app);
        end

        % Value changed function: TipodefiltroDropDown
        function TipodefiltroDropDownValueChanged2(app, event)
            value = app.TipodefiltroDropDown.Value;
            switch value
                case 'LPF'  % Low-Pass Filter
                    app.type_fir = 'low';
                    app.Frecuenciadecorte1EditField.Enable = 'on';
                    app.Frecuenciadecorte2EditField.Enable = 'off';
                    app.RadioDropDown.Enable = 'off';
                    app.FrecuenciacentralEditField.Enable = 'off';
                    app.two_freq_flag = 0;

                case 'HPF'  % High-Pass Filter
                    app.type_fir = 'high';
                    app.Frecuenciadecorte1EditField.Enable = 'on';
                    app.Frecuenciadecorte2EditField.Enable = 'off';
                    app.RadioDropDown.Enable = 'off';
                    app.FrecuenciacentralEditField.Enable = 'off';
                    app.two_freq_flag = 0;

                case 'PBF'  % Pass-Band Filter
                    app.type_fir = 'bandpass';
                    app.Frecuenciadecorte1EditField.Enable = 'on';
                    app.Frecuenciadecorte2EditField.Enable = 'on';
                    app.RadioDropDown.Enable = 'off';
                    app.FrecuenciacentralEditField.Enable = 'off';
                    app.two_freq_flag = 1;

                case 'DeleteBF'  % Stop-Band Filter (Bandstop)
                    app.type_fir = 'stop';
                    app.Frecuenciadecorte1EditField.Enable = 'on';
                    app.Frecuenciadecorte2EditField.Enable = 'on';
                    app.RadioDropDown.Enable = 'off';
                    app.FrecuenciacentralEditField.Enable = 'off';
                    app.two_freq_flag = 1;

                case 'Notch'  % Notch Filter (frecuencia específica)
                    app.type_fir = 'notch';  % ← valor personalizado, deberás manejarlo tú
                    app.Frecuenciadecorte1EditField.Enable = 'off';
                    app.Frecuenciadecorte2EditField.Enable = 'off';
                    app.RadioDropDown.Enable = 'on';
                    app.FrecuenciacentralEditField.Enable = 'on';

                case 'Peak'  % Peak Filter (resonancia)
                    app.type_fir = 'peak';   % ← también personalizado
                    app.Frecuenciadecorte1EditField.Enable = 'off';
                    app.Frecuenciadecorte2EditField.Enable = 'off';
                    app.RadioDropDown.Enable = 'on';
                    app.FrecuenciacentralEditField.Enable = 'on';
                otherwise
                    app.type_fir = 'low';  % Valor por defecto
                    app.Frecuenciadecorte1EditField.Enable = 'on';
                    app.Frecuenciadecorte2EditField.Enable = 'off';
                    app.RadioDropDown.Enable = 'off';
                    app.FrecuenciacentralEditField.Enable = 'off';
            end
        end

        % Value changed function: OrdenEditField
        function OrdenEditFieldValueChanged(app, event)
            value = app.OrdenEditField.Value;
            if value < 0
                uialert(app.UIFigure, ...
                    'Debe ingresar un valor válido para el orden del filtro.');
                % Salir del callback sin hacer cambios
                return;
            end
            app.orden_fir = value;
            RegenerarVentana(app);
        end

        % Value changed function: Frecuenciadecorte1EditField
        function Frecuenciadecorte1EditFieldValueChanged(app, event)
            value = app.Frecuenciadecorte1EditField.Value;

            % Validar Nyquist
            if value >= app.Fs / 2
                uialert(app.UIFigure, ...
                    sprintf('La frecuencia de corte 1 debe ser menor que Fs/2 (%.2f Hz).', app.Fs/2), ...
                    'Frecuencia no válida');
                app.Frecuenciadecorte1EditField.Value = 0;  % Reset opcional
                app.fc_fir = 0;
                app.wc_norm_fir = 0;
                return;
            end
            if value >= app.fc2_fir
                uialert(app.UIFigure, ...
                    'Frecuencia de corte 1 debe ser menor que la frecuencia de corte 2', ...
                    'Error en frecuencias');
                return;
            end

            app.fc_fir = value;
            if app.two_freq_flag == 0
                app.wc_norm_fir = 2 * app.fc_fir * app.Ts;
            else
                Fc2_2 = [app.fc_fir app.fc2_fir];
                app.wc_norm_fir = 2*Fc2_2*app.Ts;  % (2*pi*Fc)*Ts/pi
            end
        end

        % Value changed function: Frecuenciadecorte2EditField
        function Frecuenciadecorte2EditFieldValueChanged(app, event)
            value = app.Frecuenciadecorte2EditField.Value;

            % Validar Nyquist
            if value >= app.Fs / 2
                uialert(app.UIFigure, ...
                    sprintf('La frecuencia de corte 2 debe ser menor que Fs/2 (%.2f Hz).', app.Fs/2), ...
                    'Frecuencia no válida');
                app.Frecuenciadecorte2EditField.Value = 0;  % Reset opcional
                app.fc2_fir = 0;
                app.wc2_norm_fir = 0;
                return;
            end
            if value < app.fc_fir
                uialert(app.UIFigure, ...
                    'Frecuencia de corte 2 debe ser mayor que la frecuencia de corte 1', ...
                    'Error en frecuencias');
                return;
            end
            app.fc2_fir = value;
            Fc2_2 = [app.fc_fir app.fc2_fir];
            app.wc_norm_fir = 2*Fc2_2*app.Ts;  % (2*pi*Fc)*Ts/pi
        end

        % Button pushed function: FiltrarButton
        function FiltrarButtonPushed(app, event)
            if app.ListBox_LocalFile.ValueIndex == 1
                uialert(app.UIFigure, ...
                    'Por favor, selecciona una señal antes de filtrar.', ...
                    'Señal no seleccionada');
                return;
            end

            % Validar frecuencia de corte
            if app.Frecuenciadecorte1EditField.Enable == 'on'
                if app.Frecuenciadecorte1EditField.Value == 0
                    uialert(app.UIFigure, ...
                        'Por favor, ingresa una frecuencia de corte 1 válida.', ...
                        'Falta frecuencia de corte 1');
                    return;
                end
            end

            if app.Frecuenciadecorte2EditField.Enable == 'on'
                if app.Frecuenciadecorte2EditField.Value == 0
                    uialert(app.UIFigure, ...
                        'Por favor, ingresa una frecuencia de corte 2 válida.', ...
                        'Falta frecuencia de corte 2');
                    return;
                end
            end

            % Validar orden del filtro
            if app.OrdenEditField.Value == 0
                uialert(app.UIFigure, ...
                    'Por favor, ingresa el orden del filtro (mayor a cero).', ...
                    'Falta orden');
                return;
            end

            % Validar selección de ventana (índice 1 es "Ninguna")
            if app.VentanaDropDown.Enable == 'on'

            end

            % Validar tipo de filtro (índice 1 es "Ninguno")
            if app.TipodefiltroDropDown.ValueIndex == 1

            end

            % Si todo está bien, generar el filtro

            Generar_Filtro_FIR(app);
        end

        % Callback function
        function FIR_AmplitudSelectionChanged(app, event)
            n = length(app.x);
            [filter_H, filter_f] = freqz(app.bn_fir, app.an_fir, n, app.Fs);

            % Determinar el tipo de visualización
            selectedButton = app.FIR_Amplitud.SelectedObject;

            if selectedButton == app.FIR_Abs
                Y = abs(filter_H);
                ylabel(app.FIR_Mag, 'Amplitud');
            elseif selectedButton == app.FIR_Db
                Y = 20 * log10(abs(filter_H) + eps); % Evita log(0)
                ylabel(app.FIR_Mag, 'Magnitud [dB]');
            end

            % Replot
            plot(app.FIR_Mag, filter_f, Y);

        end

        % Drop down opening function: VentanaDropDown
        function VentanaDropDownOpening(app, event)
            if app.OrdenEditField.Value == 0
                uialert(app.UIFigure, ...
                    'Debe ingresar un valor válido para el orden del filtro antes de seleccionar la ventana.', ...
                    'Orden no definido');
                % Salir del callback sin hacer cambios
                return;
            end
        end

        % Selection changed function: FiltroAmpButtonGroup
        function FiltroAmpButtonGroupSelectionChanged(app, event)
            Generar_Filtro_FIR(app);
        end

        % Selection changed function: FiltroFaseButtonGroup
        function FiltroFaseButtonGroupSelectionChanged(app, event)
            Generar_Filtro_FIR(app);
        end

        % Selection changed function: FiltroFrecButtonGroup
        function FiltroFrecButtonGroupSelectionChanged(app, event)
            Generar_Filtro_FIR(app);
        end

        % Drop down opening function: FormaDropDown
        function FormaDropDownOpening(app, event)
            if app.OrdenEditField.Value == 0
                uialert(app.UIFigure, ...
                    'Debe ingresar un valor válido para el orden del filtro antes de seleccionar el tipo de filtro.', ...
                    'Orden no definido');
                % Salir del callback sin hacer cambios
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'Excel.png');
            app.TipoDeProcesamiento = uitabgroup(app.UIFigure);
            app.LocalTab = uitab(app.TipoDeProcesamiento);
            app.LocalTab.Title = 'Local';
            app.SealListBoxLabel = uilabel(app.LocalTab);
            app.SealListBoxLabel.Text = 'Señal';
            app.ListBox_LocalFile = uilistbox(app.LocalTab);
            app.ListBox_LocalFile.Items = {'Ninguno', 'Guitarra', 'Bajo', 'ECG', 'AAAA', 'Dona e mobile', 'Imperial march', 'Blah'};
            app.ListBox_LocalFile.ValueChangedFcn = createCallbackFcn(app, @ListBox_LocalFileValueChanged, true);
            app.ListBox_LocalFile.Value = 'Ninguno';
            app.SonidoButtonGroup = uibuttongroup(app.LocalTab);
            app.SonidoButtonGroup.Title = 'Sonido';
            app.ReproducirButton = uibutton(app.SonidoButtonGroup, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Enable = 'off';
            app.ReproducirButton.Text = 'Reproducir';
            app.PausarButton = uibutton(app.SonidoButtonGroup, 'push');
            app.PausarButton.ButtonPushedFcn = createCallbackFcn(app, @PausarButtonPushed, true);
            app.PausarButton.Enable = 'off';
            app.PausarButton.Text = 'Pausar';
            app.ReanudarButton = uibutton(app.SonidoButtonGroup, 'push');
            app.ReanudarButton.ButtonPushedFcn = createCallbackFcn(app, @ReanudarButtonPushed, true);
            app.ReanudarButton.Enable = 'off';
            app.ReanudarButton.Text = 'Reanudar';
            app.DetenerButton = uibutton(app.SonidoButtonGroup, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Enable = 'off';
            app.DetenerButton.Text = 'Detener';
            app.TiempoRealTab = uitab(app.TipoDeProcesamiento);
            app.TiempoRealTab.Title = 'Tiempo Real';
            app.CapturarButton = uibutton(app.TiempoRealTab, 'push');
            app.CapturarButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarButtonPushed, true);
            app.CapturarButton.Text = 'Capturar';
            app.DuracionsEditFieldLabel = uilabel(app.TiempoRealTab);
            app.DuracionsEditFieldLabel.Text = 'Duracion (s)';
            app.DuracionsEditField = uieditfield(app.TiempoRealTab, 'numeric');
            app.DuracionsEditField.Limits = [0 Inf];
            app.FrecMuestreoEditFieldLabel = uilabel(app.TiempoRealTab);
            app.FrecMuestreoEditFieldLabel.Text = 'Frec. Muestreo';
            app.FrecMuestreoEditField = uieditfield(app.TiempoRealTab, 'numeric');
            app.FrecMuestreoEditField.Limits = [0 Inf];
            app.PuertoDropDownLabel = uilabel(app.TiempoRealTab);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.TiempoRealTab);
            app.PuertoDropDown.Items = {'COM7', 'COM9', 'COM11', 'COM13'};
            app.PuertoDropDown.Value = 'COM7';
            app.SinProcesarPanel = uipanel(app.UIFigure);
            app.SinProcesarPanel.Title = 'Señal cargada/muestreada';
            app.OriginalTiempoAxes = uiaxes(app.SinProcesarPanel);
            title(app.OriginalTiempoAxes, 'Señal Original')
            xlabel(app.OriginalTiempoAxes, 'Tiempo (s)')
            ylabel(app.OriginalTiempoAxes, 'Amplitud')
            zlabel(app.OriginalTiempoAxes, 'Z')
            app.OriginalEspectroAxes = uiaxes(app.SinProcesarPanel);
            title(app.OriginalEspectroAxes, 'Espectro Señal Original')
            xlabel(app.OriginalEspectroAxes, 'Frecuencia (Hz)')
            ylabel(app.OriginalEspectroAxes, 'Amplitud')
            zlabel(app.OriginalEspectroAxes, 'Z')
            app.OriginalEspectroAxes.XLim = [0 Inf];
            app.OriginalEspectroAxes.YLim = [0 Inf];
            app.OrgAmpButtonGroup = uibuttongroup(app.SinProcesarPanel);
            app.OrgAmpButtonGroup.Title = 'Amplitud';
            app.absButton_org = uiradiobutton(app.OrgAmpButtonGroup);
            app.absButton_org.Text = 'Abs';
            app.absButton_org.Value = true;
            app.dBbutton_org = uiradiobutton(app.OrgAmpButtonGroup);
            app.dBbutton_org.Text = 'dB';
            app.OrgFrecButtonGroup = uibuttongroup(app.SinProcesarPanel);
            app.OrgFrecButtonGroup.Title = 'Frecuencia';
            app.HzButton_org = uiradiobutton(app.OrgFrecButtonGroup);
            app.HzButton_org.Text = 'Hz';
            app.HzButton_org.Value = true;
            app.radmButton_org = uiradiobutton(app.OrgFrecButtonGroup);
            app.radmButton_org.Text = 'rad/muestra';
            app.FsEditFieldLabel = uilabel(app.SinProcesarPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SinProcesarPanel, 'numeric');
            app.ProcesadaPanel = uipanel(app.UIFigure);
            app.ProcesadaPanel.Title = 'Señal procesada';
            app.ProcesadaEspectroAxes = uiaxes(app.ProcesadaPanel);
            title(app.ProcesadaEspectroAxes, 'Espectro Señal Procesada')
            xlabel(app.ProcesadaEspectroAxes, 'Frecuencia (Hz)')
            ylabel(app.ProcesadaEspectroAxes, 'Amplitud')
            zlabel(app.ProcesadaEspectroAxes, 'Z')
            app.ProcesadaTiempoAxes = uiaxes(app.ProcesadaPanel);
            title(app.ProcesadaTiempoAxes, 'Señal Procesada')
            xlabel(app.ProcesadaTiempoAxes, 'Tiempo (s)')
            ylabel(app.ProcesadaTiempoAxes, 'Amplitud')
            zlabel(app.ProcesadaTiempoAxes, 'Z')
            app.ProcAmpButtonGroup = uibuttongroup(app.ProcesadaPanel);
            app.ProcAmpButtonGroup.Title = 'Amplitud';
            app.absButton_proc = uiradiobutton(app.ProcAmpButtonGroup);
            app.absButton_proc.Text = 'Abs';
            app.absButton_proc.Value = true;
            app.dBbutton_proc = uiradiobutton(app.ProcAmpButtonGroup);
            app.dBbutton_proc.Text = 'dB';
            app.ProcFrecButtonGroup = uibuttongroup(app.ProcesadaPanel);
            app.ProcFrecButtonGroup.Title = 'Frecuencia';
            app.HzButton_proc = uiradiobutton(app.ProcFrecButtonGroup);
            app.HzButton_proc.Text = 'Hz';
            app.HzButton_proc.Value = true;
            app.radmButton_proc = uiradiobutton(app.ProcFrecButtonGroup);
            app.radmButton_proc.Text = 'rad/muestra';
            app.DisenadorDeFiltros = uitabgroup(app.UIFigure);
            app.DisearfiltroTab = uitab(app.DisenadorDeFiltros);
            app.DisearfiltroTab.Title = 'Diseñar filtro';
            app.DuraciondelfiltroButtonGroup = uibuttongroup(app.DisearfiltroTab);
            app.DuraciondelfiltroButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DuraciondelfiltroButtonGroupSelectionChanged, true);
            app.DuraciondelfiltroButtonGroup.Title = 'Duracion del filtro';
            app.IIRButton = uiradiobutton(app.DuraciondelfiltroButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.FIRButton = uiradiobutton(app.DuraciondelfiltroButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.FIRButton.Value = true;
            app.Tree = uitree(app.DisearfiltroTab, 'checkbox');
            app.EfectosNode = uitreenode(app.Tree);
            app.EfectosNode.Text = 'Efectos';
            app.SlowedNode = uitreenode(app.EfectosNode);
            app.SlowedNode.Text = 'Slowed';
            app.ReverbNode = uitreenode(app.EfectosNode);
            app.ReverbNode.Text = 'Reverb';
            app.FastNode = uitreenode(app.EfectosNode);
            app.FastNode.Text = 'Fast';
            app.VentanaDropDownLabel = uilabel(app.DisearfiltroTab);
            app.VentanaDropDownLabel.Text = 'Ventana';
            app.VentanaDropDown = uidropdown(app.DisearfiltroTab);
            app.VentanaDropDown.Items = {'Rectangular', 'Bartlett', 'Hamming', 'Blackman', 'Kaiser'};
            app.VentanaDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VentanaDropDownOpening, true);
            app.VentanaDropDown.ValueChangedFcn = createCallbackFcn(app, @VentanaDropDownValueChanged, true);
            app.VentanaDropDown.Value = 'Hamming';
            app.FormaDropDownLabel = uilabel(app.DisearfiltroTab);
            app.FormaDropDownLabel.Text = 'Forma';
            app.FormaDropDown = uidropdown(app.DisearfiltroTab);
            app.FormaDropDown.Items = {'Butterworth', 'Chebyshev I', 'Chebyshev II', 'Eliptico'};
            app.FormaDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FormaDropDownOpening, true);
            app.FormaDropDown.Value = 'Butterworth';
            app.CargarflitroDropDownLabel = uilabel(app.DisearfiltroTab);
            app.CargarflitroDropDownLabel.Text = 'Cargar flitro';
            app.CargarflitroDropDown = uidropdown(app.DisearfiltroTab);
            app.GuardarButton = uibutton(app.DisearfiltroTab, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.FiltrarButton = uibutton(app.DisearfiltroTab, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarButtonPushed, true);
            app.FiltrarButton.Text = 'Filtrar';
            app.TipodefiltroDropDownLabel = uilabel(app.DisearfiltroTab);
            app.TipodefiltroDropDownLabel.Text = 'Tipo  de filtro';
            app.TipodefiltroDropDown = uidropdown(app.DisearfiltroTab);
            app.TipodefiltroDropDown.Items = {'LPF', 'HPF', 'PBF', 'DeleteBF', 'Notch', 'Peak'};
            app.TipodefiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodefiltroDropDownValueChanged2, true);
            app.TipodefiltroDropDown.Value = 'LPF';
            app.Frecuenciadecorte1EditFieldLabel = uilabel(app.DisearfiltroTab);
            app.Frecuenciadecorte1EditFieldLabel.Text = 'Frecuencia de corte 1';
            app.Frecuenciadecorte1EditField = uieditfield(app.DisearfiltroTab, 'numeric');
            app.Frecuenciadecorte1EditField.Limits = [0 Inf];
            app.Frecuenciadecorte1EditField.ValueChangedFcn = createCallbackFcn(app, @Frecuenciadecorte1EditFieldValueChanged, true);
            app.Frecuenciadecorte2EditFieldLabel = uilabel(app.DisearfiltroTab);
            app.Frecuenciadecorte2EditFieldLabel.Text = 'Frecuencia de corte 2';
            app.Frecuenciadecorte2EditField = uieditfield(app.DisearfiltroTab, 'numeric');
            app.Frecuenciadecorte2EditField.Limits = [0 Inf];
            app.Frecuenciadecorte2EditField.ValueChangedFcn = createCallbackFcn(app, @Frecuenciadecorte2EditFieldValueChanged, true);
            app.OrdenEditFieldLabel = uilabel(app.DisearfiltroTab);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.OrdenEditField = uieditfield(app.DisearfiltroTab, 'numeric');
            app.OrdenEditField.Limits = [0 Inf];
            app.OrdenEditField.ValueChangedFcn = createCallbackFcn(app, @OrdenEditFieldValueChanged, true);
            app.FrecuenciacentralEditFieldLabel = uilabel(app.DisearfiltroTab);
            app.FrecuenciacentralEditFieldLabel.Text = 'Frecuencia central';
            app.FrecuenciacentralEditField = uieditfield(app.DisearfiltroTab, 'numeric');
            app.FrecuenciacentralEditField.Limits = [0 Inf];
            app.RadioDropDownLabel = uilabel(app.DisearfiltroTab);
            app.RadioDropDownLabel.Text = 'Radio';
            app.RadioDropDown = uidropdown(app.DisearfiltroTab);
            app.RadioDropDown.Items = {'0.999', '0.995', '0.99', '0.95', '0.9', '0.5'};
            app.RadioDropDown.Value = '0.999';
            app.VerfiltroTab = uitab(app.DisenadorDeFiltros);
            app.VerfiltroTab.Title = 'Ver filtro';
            app.FiltroFaseAxes = uiaxes(app.VerfiltroTab);
            title(app.FiltroFaseAxes, 'Fase')
            xlabel(app.FiltroFaseAxes, 'Frecuencia (kHz)')
            ylabel(app.FiltroFaseAxes, 'Fase (grados)')
            zlabel(app.FiltroFaseAxes, 'Z')
            app.FiltroFaseAxes.YLim = [-Inf Inf];
            app.FiltroMagnitudAxes = uiaxes(app.VerfiltroTab);
            title(app.FiltroMagnitudAxes, 'Magnitud')
            xlabel(app.FiltroMagnitudAxes, 'Frecuencia (kHz)')
            ylabel(app.FiltroMagnitudAxes, 'Amplitud (abs)')
            zlabel(app.FiltroMagnitudAxes, 'Z')
            app.FiltroMagnitudAxes.YLim = [-Inf Inf];
            app.FiltroAmpButtonGroup = uibuttongroup(app.VerfiltroTab);
            app.FiltroAmpButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltroAmpButtonGroupSelectionChanged, true);
            app.FiltroAmpButtonGroup.Title = 'Amplitud';
            app.absButton_filtro = uiradiobutton(app.FiltroAmpButtonGroup);
            app.absButton_filtro.Text = 'Abs';
            app.absButton_filtro.Value = true;
            app.dBbutton_filtro = uiradiobutton(app.FiltroAmpButtonGroup);
            app.dBbutton_filtro.Text = 'dB';
            app.FiltroFrecButtonGroup = uibuttongroup(app.VerfiltroTab);
            app.FiltroFrecButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltroFrecButtonGroupSelectionChanged, true);
            app.FiltroFrecButtonGroup.Title = 'Frecuencia';
            app.HzButton_filtro = uiradiobutton(app.FiltroFrecButtonGroup);
            app.HzButton_filtro.Text = 'kHz';
            app.HzButton_filtro.Value = true;
            app.radmButton_filtro = uiradiobutton(app.FiltroFrecButtonGroup);
            app.radmButton_filtro.Text = 'rad/muestra';
            app.FiltroFaseButtonGroup = uibuttongroup(app.VerfiltroTab);
            app.FiltroFaseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltroFaseButtonGroupSelectionChanged, true);
            app.FiltroFaseButtonGroup.Title = 'Fase';
            app.gradosButton_filtro = uiradiobutton(app.FiltroFaseButtonGroup);
            app.gradosButton_filtro.Text = 'Grados';
            app.gradosButton_filtro.Value = true;
            app.radButton_filtro = uiradiobutton(app.FiltroFaseButtonGroup);
            app.radButton_filtro.Text = 'rad';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PROEYCTO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end